classdef Traffic_ff < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        app_text                  matlab.ui.control.Hyperlink
        showans                   matlab.ui.control.Button
        TrafficFlowForecastLabel  matlab.ui.control.Label
        StartcalculationButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Description
        Dialogapp;
        file_name;
        Pnameh;
       
    end
    
    methods (Access = public)
        
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          app.UIFigure.Name='Traffic_ff';
        end

        % Button pushed function: StartcalculationButton
        function StartcalculationButtonPushed(app, event)
            run parallel_column_sf_1030.m
            msgbox('Finish calculation')
        end

        % Button pushed function: showans
        function showansPushed(app, event)
            
            app.Dialogapp=show_ans(app,app.Pnameh);
            
        end

        % Callback function
        function readexcelPushed(app, event)
            
        end

        % Callback function: app_text
        function app_textClicked(app, event)

            msgbox({ '1、First put the input information in the mat file'; '2、Second click the Start button and you can select the input mat file';'3、Input information includes: travel demand (including OD-demond travel origin and destination), travel time parameters for urban roads'},'Description')
           
        end

        % Callback function
        function get_addressButtonPushed(app, event)
            [app.file_name,app.Pnameh]=load_file();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartcalculationButton = uibutton(app.UIFigure, 'push');
            app.StartcalculationButton.ButtonPushedFcn = createCallbackFcn(app, @StartcalculationButtonPushed, true);
            app.StartcalculationButton.Text = 'Start calculation';
            app.TrafficFlowForecastLabel = uilabel(app.UIFigure);
            app.TrafficFlowForecastLabel.Text = 'Traffic Flow Forecast';
            app.showans = uibutton(app.UIFigure, 'push');
            app.showans.ButtonPushedFcn = createCallbackFcn(app, @showansPushed, true);
            app.showans.Text = 'Show results';
            app.app_text = uihyperlink(app.UIFigure);
            app.app_text.HyperlinkClickedFcn = createCallbackFcn(app, @app_textClicked, true);
            app.app_text.Text = 'Program description';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Traffic_ff

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end